classdef simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ModulationOrderEditField        matlab.ui.control.NumericEditField
        ModulationOrderEditFieldLabel   matlab.ui.control.Label
        BasestationNoisedbEditField     matlab.ui.control.NumericEditField
        BasestationNoisedbEditFieldLabel  matlab.ui.control.Label
        VanAttaelementsEditField        matlab.ui.control.NumericEditField
        VanAttaelementsEditFieldLabel   matlab.ui.control.Label
        symbolfreqEditField             matlab.ui.control.NumericEditField
        symbolfreqEditFieldLabel        matlab.ui.control.Label
        FbStepEditField                 matlab.ui.control.NumericEditField
        FbStepEditFieldLabel            matlab.ui.control.Label
        InterchannelSpacingEditField    matlab.ui.control.NumericEditField
        InterchannelSpacingEditFieldLabel  matlab.ui.control.Label
        amplitudeEditField              matlab.ui.control.NumericEditField
        amplitudeEditFieldLabel         matlab.ui.control.Label
        ofsymbolsEditField              matlab.ui.control.NumericEditField
        ofsymbolsEditFieldLabel         matlab.ui.control.Label
        FbBaseEditField                 matlab.ui.control.NumericEditField
        FbBaseEditFieldLabel            matlab.ui.control.Label
        simstepspersymbolEditField      matlab.ui.control.NumericEditField
        simstepspersymbolEditFieldLabel  matlab.ui.control.Label
        FsGranularityEditField          matlab.ui.control.NumericEditField
        FsGranularityEditFieldLabel     matlab.ui.control.Label
        SimulationParametersLabel       matlab.ui.control.Label
        CarrierFrequencyEditField       matlab.ui.control.NumericEditField
        CarrierFrequencyEditFieldLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        TagLocationsTab                 matlab.ui.container.Tab
        MaxXEditFieldLabel              matlab.ui.control.Label
        MaxYEditFieldLabel              matlab.ui.control.Label
        MaxZEditFieldLabel              matlab.ui.control.Label
        deleteidxEditFieldLabel         matlab.ui.control.Label
        NumTagsEditFieldLabel           matlab.ui.control.Label
        TypeDropDownLabel               matlab.ui.control.Label
        ZEditFieldLabel                 matlab.ui.control.Label
        YEditFieldLabel                 matlab.ui.control.Label
        XEditFieldLabel                 matlab.ui.control.Label
        MaxXEditField                   matlab.ui.control.NumericEditField
        MaxYEditField                   matlab.ui.control.NumericEditField
        MaxZEditField                   matlab.ui.control.NumericEditField
        DeleteButton                    matlab.ui.control.Button
        deleteidxEditField              matlab.ui.control.NumericEditField
        CreateButton                    matlab.ui.control.Button
        ManualCreationLabel             matlab.ui.control.Label
        RandomCreationLabel             matlab.ui.control.Label
        RandomFChannelCheckBox          matlab.ui.control.CheckBox
        NumTagsEditField                matlab.ui.control.NumericEditField
        AddButton                       matlab.ui.control.Button
        TypeDropDown                    matlab.ui.control.DropDown
        UITable_3                       matlab.ui.control.Table
        ZEditField                      matlab.ui.control.NumericEditField
        YEditField                      matlab.ui.control.NumericEditField
        XEditField                      matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        SimulationTab                   matlab.ui.container.Tab
        CustomBitstreamEditField        matlab.ui.control.EditField
        CustomBitstreamEditFieldLabel   matlab.ui.control.Label
        RandomBitstreamCheckBox         matlab.ui.control.CheckBox
        ApplyBasestationNoiseCheckBox   matlab.ui.control.CheckBox
        ApplyNoiseCheckBox              matlab.ui.control.CheckBox
        SNRdbEditField                  matlab.ui.control.NumericEditField
        SNRdbEditFieldLabel             matlab.ui.control.Label
        TagidxEditField                 matlab.ui.control.NumericEditField
        TagidxEditFieldLabel            matlab.ui.control.Label
        NumSimStepsEditField            matlab.ui.control.NumericEditField
        NumSimStepsEditFieldLabel       matlab.ui.control.Label
        DemodulateButton                matlab.ui.control.Button
        UITable_4                       matlab.ui.control.Table
        AlignButton                     matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        DataSigButton                   matlab.ui.control.Button
        SimulationLabel                 matlab.ui.control.Label
        SimulateButton                  matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        FrequencyHoppingTab             matlab.ui.container.Tab
        SpectrogramButton               matlab.ui.control.Button
        BandpassFilterCheckBox          matlab.ui.control.CheckBox
        ChannelEditField                matlab.ui.control.NumericEditField
        ChannelEditFieldLabel           matlab.ui.control.Label
        ActiveTagsEditField             matlab.ui.control.NumericEditField
        ActiveTagsEditFieldLabel        matlab.ui.control.Label
        ResetButton_2                   matlab.ui.control.Button
        FourierTransformButton          matlab.ui.control.Button
        TimeDomainSignalButton          matlab.ui.control.Button
        SimulateButton_2                matlab.ui.control.Button
        NumChannelsEditField            matlab.ui.control.NumericEditField
        NumChannelsEditFieldLabel       matlab.ui.control.Label
        PatternLengthEditField          matlab.ui.control.NumericEditField
        PatternLengthEditFieldLabel     matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        curr_point = [0, 0, 0]; % Current point being drawn on-screen
        curr_type = "FSK_LO";
        all_points = [];
        all_types = strings(0);
        modded_steps = [];
        sim = [];
        aligned = struct("signal", [], "time", [], "carrier", []);
    end

    properties (Access = public, Dependent)
        simconsts
    end
    
    methods
        
        function set.curr_point(app, coords)
            arguments
                app
                coords (1,3)
            end

            disp(coords);
            app.curr_point = coords;
        end

        function res = get.simconsts(app)
            import bherber.thesis.SimulationConstants
            res = SimulationConstants( ...
                    app.CarrierFrequencyEditField.Value, ... "Fc"
                    app.FbBaseEditField.Value, ... "Fb_base"
                    app.FbStepEditField.Value, ... "Fb_step"
                    app.InterchannelSpacingEditField.Value, ... "Fb_channel_spacing"
                    app.NumChannelsEditField.Value, ... "num_channels"
                    app.FsGranularityEditField.Value, ... "fs_granularity"
                    app.ofsymbolsEditField.Value, ... "num_symbs"
                    app.symbolfreqEditField.Value, ... "symb_freq"
                    app.simstepspersymbolEditField.Value, ... "sim_sym_ratio"
                    app.amplitudeEditField.Value, ... "amplitude"
                    app.VanAttaelementsEditField.Value,  ... "num_elements"
                    app.PatternLengthEditField.Value, ... "pattern_len"
                    app.ModulationOrderEditField.Value ... "m_ary_modulation"
                );
        end

    end
    
    methods (Access = private)
        
        function plot_curr(app)
            scatter(app.UIAxes, app.curr_point(1), app.curr_point(2), "*r")
        end
        
        function make_table(app)
            import bherber.thesis.TagType
            distances = sqrt(app.all_points(:, 1) .^2 + app.all_points(:, 2) .^2 + ...
                app.all_points(:, 3) .^2);
            app.UITable_3.Data = [(1:length(app.all_types)).', app.all_points(:, 1), ...
                app.all_points(:, 2), app.all_points(:, 3), distances(:), app.all_types(:)];
            app.UITable_3.ForegroundColor
            
            cla(app.UIAxes);
            hold(app.UIAxes, "on");
            scatter(app.UIAxes, 0, 0, "hk", MarkerFaceColor="black")
            for idx = 1:length(app.all_types)
                if app.all_types(idx) == "FREQ_HOP"
                    scatter(app.UIAxes, app.all_points(idx, 1), app.all_points(idx, 2), ...
                        Marker="v", MarkerEdgeColor="#EB4034", MarkerFaceColor="#EB4034")
                elseif app.all_types(idx) == "FSK_LO"
                    scatter(app.UIAxes, app.all_points(idx, 1), app.all_points(idx, 2), ...
                        Marker="v", MarkerEdgeColor="#1B5299", MarkerFaceColor="#1B5299")
                elseif app.all_types(idx) == "FSK_HI"
                    scatter(app.UIAxes, app.all_points(idx, 1), app.all_points(idx, 2), ...
                        Marker="^", MarkerEdgeColor="#48BEFF", MarkerFaceColor="#48BEFF")
                else
                    scatter(app.UIAxes, app.all_points(idx, 1), app.all_points(idx, 2), ...
                        Marker="v", MarkerEdgeColor="#DEB841", MarkerFaceColor="#DEB841")
                end
            end

            if ~isempty(app.all_points) && (max(app.all_points(:, 1:2), [], "all") ~= 0)
                lim = 1.2 * abs(max(abs(app.all_points(:, 1:2)), [], "all"));
            else
                lim = 2;
            end

            app.UIAxes.XLim = [-lim, lim];
            app.UIAxes.YLim = [-lim, lim];

            hold(app.UIAxes, "off");
        end
        
        function make_fh_heatmap(app)
            pattern_len = length(app.sim.tags(1).hop_pattern);
            data = zeros(length(app.sim.tags), pattern_len);
            for idx = 1:length(app.sim.tags)
                data(idx, :) = app.sim.tags(idx).hop_pattern;
            end
            xlabels = strings(1, pattern_len + 1);
            for idx = 1:pattern_len
                xlabels(idx) = string(idx) + "T_s";
            end
            ylabels = strings(1, length(app.sim.tags));
            for idx = 1:length(app.sim.tags)
                ylabels(idx) = "tag" + string(idx);
            end
            cb_labels = strings(1, length(app.sim.tags));
            for idx = 1:app.NumChannelsEditField.Value
                cb_labels(idx) = "subband" + string(idx);
            end
            imagesc(app.UIAxes3, data, [1, app.NumChannelsEditField.Value]);
            colorbar(app.UIAxes3, Ticks=1:app.NumChannelsEditField.Value, TickLabels=cb_labels);
            title(app.UIAxes3, "Hop-Patterns for All Tags in Network");
            xlabel(app.UIAxes3, "Hop-Patterns (symbol time)");
            ylabel(app.UIAxes3, "Tag Number");
            xticks(app.UIAxes3, 1.5:1:(pattern_len + 1));
            xticklabels(app.UIAxes3, xlabels);
            xlim(app.UIAxes3, [0.5, 0.5 + pattern_len]);
            ylim(app.UIAxes3, [0.5, 0.5 + length(app.sim.tags)]);
            yticks(app.UIAxes3, 1:length(app.sim.tags));
            yticklabels(app.UIAxes3, ylabels);
            colormap(app.UIAxes3, hsv(app.NumChannelsEditField.Value));
        end
        
        function plot_active(app)
            cla(app.UIAxes4);
            title(app.UIAxes4, "Tag Locations and Activity");
            xlabel(app.UIAxes4, "x (m)");
            ylabel(app.UIAxes4, "y (m)")
            hold(app.UIAxes4, "on");
            scatter(app.UIAxes4, 0, 0, "hk", MarkerFaceColor="black")
            for idx = 1:length(app.all_types)
                if ~app.sim.tags(idx).is_active
                    scatter(app.UIAxes4, app.sim.tags(idx).x, app.sim.tags(idx).y, ...
                        Marker="v", MarkerEdgeColor="#EB4034", MarkerFaceColor="#EB4034")
                else
                    scatter(app.UIAxes4, app.sim.tags(idx).x, app.sim.tags(idx).y, ...
                        Marker="^", MarkerEdgeColor="#DEB841", MarkerFaceColor="#DEB841")
                    text(app.UIAxes4, app.sim.tags(idx).x, app.sim.tags(idx).y, sprintf("tag%d", idx), ...
                        VerticalAlignment="top", HorizontalAlignment="left");
                end
            end

            if ~isempty(app.all_points) && (max(app.all_points(:, 1:2), [], "all") ~= 0)
                lim = 1.2 * abs(max(abs(app.all_points(:, 1:2)), [], "all"));
            else
                lim = 2;
            end

            app.UIAxes4.XLim = [-lim, lim];
            app.UIAxes4.YLim = [-lim, lim];

            hold(app.UIAxes4, "off");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            fhv = app.UIFigure.HandleVisibility;
            app.UIFigure.HandleVisibility = "callback";
            set(0, "CurrentFigure", app.UIFigure)
            
            [x, y, ~] = ginput(1);
    
            app.UIFigure.HandleVisibility = fhv;
            
            app.curr_point = [x, y, 0];
            app.XEditField.Value = x;
            app.YEditField.Value = y;
            app.ZEditField.Value = 0;

            app.plot_curr();
            
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            app.curr_point(1) = app.XEditField.Value;
            app.plot_curr();
            % UPDATE VIEW
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            app.curr_point(2) = app.YEditField.Value;
            app.plot_curr();
            % UPDATE VIEW
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            app.curr_point(3) = app.ZEditField.Value;
            app.plot_curr();
            % UPDATE VIEW
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            app.curr_type = app.TypeDropDown.Value;
            app.plot_curr();
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.all_points = [app.all_points; app.curr_point];
            app.all_types = [app.all_types; string(app.curr_type)];
            app.make_table()
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            idx = app.deleteidxEditField.Value;
            if isempty(app.all_points)
                app.all_points = [];
                app.all_types = [];
                app.UITable_3.Data = [];
            elseif size(app.all_points, 1) == 1
                app.all_points = [];
                app.all_types = [];
                app.UITable_3.Data = [];
            else
                app.all_points(idx, :) = [];
                app.all_types(idx) = [];
                app.make_table();
            end
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            types = strings(app.NumTagsEditField.Value, 1);
            if app.RandomFChannelCheckBox.Value && ...
                    ~((app.TypeDropDown.Value == "OOK") || (app.TypeDropDown.Value == "FREQ_HOP"))
                for idx = 1:length(types)
                    if logical(randi([0, 1], 1))
                        types(idx) = "FSK_LO";
                    else
                        types(idx) = "FSK_HI";
                    end
                end
            else
                for idx = 1:length(types)
                    types(idx) = app.TypeDropDown.Value;
                end
            end
            app.all_types = types;

            app.all_points = zeros(app.NumTagsEditField.Value, 3);
            x = (2 * app.MaxXEditField.Value) .* rand(app.NumTagsEditField.Value, 1) - app.MaxXEditField.Value;
            y = (2 * app.MaxYEditField.Value) .* rand(app.NumTagsEditField.Value, 1) - app.MaxYEditField.Value;
            z = (2 * app.MaxZEditField.Value) .* rand(app.NumTagsEditField.Value, 1) - app.MaxZEditField.Value;
            app.all_points(:, 1) = x(:);
            app.all_points(:, 2) = y(:);
            app.all_points(:, 3) = z(:);

            app.make_table();
        end

        % Button pushed function: SimulateButton, SimulateButton_2
        function SimulateButtonPushed(app, event)
            import bherber.thesis.Simulator bherber.thesis.Tag bherber.thesis.TagType bherber.thesis.channel_models.AWGNChannelModel
            types = repelem([TagType.FSK_LO], length(app.all_types));
            for idx = 1:length(types)
                switch app.all_types(idx)
                    case "OOK"
                        types(idx) = TagType.OOK;
                    case "FSK_LO"
                        types(idx) = TagType.FSK_LO;
                    case "FREQ_HOP"
                        types(idx) = TagType.FREQ_HOP;
                    otherwise
                        types(idx) = TagType.FSK_HI;
                end
            end

%             if app.ApplyNoiseCheckBox.Value
%                 channel = @(signal) awgn_channel(signal, snr_db=app.SNRdbEditField.Value, complex=false);
% %                   channel = @(signal) static_awgn_channel(signal, app.simconsts, mode=TagType.FSK_LO, ...
% %                       snr_db=app.SNRdbEditField.Value, distance=0);
%             else
                channel = @(signal) signal;
%             end
            
            if isempty(app.sim)
                if app.RandomBitstreamCheckBox.Value
                    bitstream = [];
                else
                    bitstream = str2double(split(app.CustomBitstreamEditField.Value, ","));
                    app.ofsymbolsEditField.Value = length(bitstream);
                end
                        
                if app.ApplyNoiseCheckBox.Value
                    app.sim = Simulator(app.all_points.', types, channel, app.simconsts, ...
                        bitstream=bitstream, snr_db=app.SNRdbEditField.Value, complex=false);
                else
                    app.sim = Simulator(app.all_points.', types, channel, app.simconsts, bitstream=bitstream);
                end
                if app.ActiveTagsEditField.Value > length(app.sim.tags)
                    active_num = length(app.sim.tags);
                else
                    active_num = app.ActiveTagsEditField.Value;
                end
                for idx = 1:length(app.sim.tags)
                    app.sim.tags(idx).deactivate();
                end
                active_tags = app.sim.tags(randperm(length(app.sim.tags), active_num));
                for idx = 1:length(active_tags)
                    active_tags(idx).activate();
                end
            end
            
            steps = zeros(int32(app.simconsts.simstep_sz), app.NumSimStepsEditField.Value);
            for idx = 1:app.NumSimStepsEditField.Value
                steps(:, idx) = app.sim.step();
            end
            app.modded_steps = steps(:);


%             for idx = 1:size(app.modded_steps, 1)
%                 app.modded_steps(idx, :) = channel(app.modded_steps(idx, :));
% %                 curr = app.sim.tags(idx);
% %                 app.modded_steps(idx, :) = static_awgn_channel(app.modded_steps(idx, :), app.simconsts, mode=curr.mode, ...
% %                       snr_db=app.SNRdbEditField.Value, distance=curr.distance);
%             end

%             if app.ApplyNoiseCheckBox.Value
%                 is_complex = false;
%                 linear_snr = 2; %10 ^ (app.SNRdbEditField.Value / 10);
%                 additive_noise = 0;
%                 noisy_carrier = AWGNChannelModel.noise(length(app.modded_steps), ...
%                                 (app.simconsts.amplitude ^ 2) / 2, linear_snr, is_complex);
%                 for idx = 1:length(app.sim.tags)
%                     if app.sim.tags(idx).is_active
%                         path_loss = (app.simconsts.wavelen / (4 * pi * app.sim.tags(idx).distance));
%                         gain = Tag.vanatta_gain(app.simconsts.num_elements, app.simconsts.Fc, app.simconsts.Fc);
%                         additive_noise = additive_noise + ...
%                             (AWGNChannelModel.noise(length(app.modded_steps), ...
%                                 (app.simconsts.amplitude ^ 2) / 2, linear_snr, is_complex) * (path_loss ^ 2) * gain);
%                         expected_amplitude = app.simconsts.amplitude * path_loss * gain;
%                         additive_noise = additive_noise + ...
%                             (AWGNChannelModel.noise(length(app.modded_steps), ...
%                                 (expected_amplitude ^ 2) / 2, linear_snr, is_complex) * path_loss);
%                     end
%                 end
%                 app.modded_steps = app.modded_steps + additive_noise.';
%             end

%             if app.ApplyBasestationNoiseCheckBox.Value
% %                 noise_power = (app.BasestationNoisedbEditField.Value / 100) * (app.amplitudeEditField.Value .^ 2);
%                 app.modded_steps = awgn_channel(sum(app.modded_steps, 1), noise_power_db=app.BasestationNoisedbEditField.Value); % FIXME: SUS
% %                 app.modded_steps = static_awgn_channel(sum(app.modded_steps, 1), app.simconsts, noise_power_db=noise_power);
%             end

            time = ((0:(double(app.simconsts.simstep_sz * app.sim.curr_step) - 1)) / app.simconsts.Fs);

            plot(app.UIAxes2, time, app.modded_steps);
            xlim(app.UIAxes2, [0, time(end)]);
            title(app.UIAxes2, "Superposition of Time-Domain Signals at Basestation", FontSize=20);
            xlabel(app.UIAxes2, "time(s)", FontSize=18);
            ylabel(app.UIAxes2, "amplitude", FontSize=18);
            xlines = zeros(1, app.ofsymbolsEditField.Value);
            for idx = 1:app.ofsymbolsEditField.Value
                xlines(idx) = idx * (1 / app.symbolfreqEditField.Value);
            end
            xline(app.UIAxes2, xlines, "--k");
            xticks(app.UIAxes2, xlines);
            xlabels = strings(1, app.ofsymbolsEditField.Value);
            for idx = 1:length(xlabels)
                xlabels(idx) = string(idx) + "T_s";
            end
            xticklabels(app.UIAxes2, xlabels);

            lim = 1.2 * abs(real(max(abs(app.modded_steps))));
            if lim == 0; lim = 1; end

            app.UIAxes2.YLim = [-lim, lim];

            if ~isempty(app.sim.tags) && (app.sim.tags(1).mode == TagType.FREQ_HOP)
                app.make_fh_heatmap();
                app.plot_active();
            end
            
        end

        % Button pushed function: ResetButton, ResetButton_2
        function ResetButtonPushed(app, event)
            app.modded_steps = [];
            app.sim = [];
            app.UITable_4.Data = [];
            cla(app.UIAxes2);
        end

        % Button pushed function: DataSigButton
        function DataSigButtonPushed(app, event)
            time = ((0:(double(app.simconsts.simstep_sz * app.sim.curr_step) - 1)) / app.simconsts.Fs);
            data = app.sim.tags(app.TagidxEditField.Value).data(start:stop) * abs(max(app.modded_steps(:)));
            hold(app.UIAxes2, "on");
            plot(app.UIAxes2, time, data);
            hold(app.UIAxes2, "off");

        end

        % Button pushed function: DemodulateButton
        function DemodulateButtonPushed(app, event)
            res_bits = zeros(log2(app.simconsts.m_ary_modulation), app.ofsymbolsEditField.Value);
            num_symbs_simd = floor(app.sim.curr_step / app.simstepspersymbolEditField.Value);

            if num_symbs_simd >= app.simconsts.num_symbs
                num_symbs_simd = app.simconsts.num_symbs;
            end

            modded_symbs = app.aligned.signal;
            for idx = 1:num_symbs_simd
                res_bits(:, idx) = app.sim.tags(app.TagidxEditField.Value).demodulate(idx, modded_symbs(:, idx));
            end

            expected = join(string(app.sim.bits(app.TagidxEditField.Value, :)), "");
            ber = biterr(app.sim.bits(app.TagidxEditField.Value, :), res_bits(:).');
            app.UITable_4.Data = [app.UITable_4.Data; [app.TagidxEditField.Value, expected, join(string(res_bits(:)), ""), string(ber)]];
        end

        % Button pushed function: AlignButton
        function AlignButtonPushed(app, event)
            app.sim.auto_align();
            delay = app.sim.tag_delays(app.TagidxEditField.Value);
            num_symbs_simd = floor(app.sim.curr_step / app.simstepspersymbolEditField.Value);
            if num_symbs_simd > app.ofsymbolsEditField.Value
                num_symbs_simd = app.ofsymbolsEditField.Value;
            end

            if app.sim.tags(app.TagidxEditField.Value).distance == 0
                steps = app.modded_steps;
            else
                steps = delayseq(app.modded_steps, -1 * delay);
            end

            stop = num_symbs_simd * double(app.simconsts.symb_sz);
            time = ((0:(double(app.simconsts.simstep_sz * app.sim.curr_step) - 1)) / app.simconsts.Fs);

            app.aligned.time = reshape(time(1:stop), [app.simconsts.symb_sz, num_symbs_simd]);
%             app.aligned.carrier = reshape(app.sim.carrier(1:stop), [app.simconsts.symb_sz, num_symbs_simd]);
            app.aligned.signal = reshape(steps(1:stop), [app.simconsts.symb_sz, num_symbs_simd]);

            plot(app.UIAxes2, app.aligned.time(:), app.aligned.signal(:));
            xlim(app.UIAxes2, [0, app.ofsymbolsEditField.Value * (1 / app.symbolfreqEditField.Value)]);
            title(app.UIAxes2, "Aligned Time-Domain Signal", FontSize=20);
            xlabel(app.UIAxes2, "time(s)", FontSize=18);
            ylabel(app.UIAxes2, "amplitude", FontSize=18);
            xlines = zeros(1, app.ofsymbolsEditField.Value);
            for idx = 1:app.ofsymbolsEditField.Value
                xlines(idx) = idx * (1 / app.symbolfreqEditField.Value);
            end
            xline(app.UIAxes2, xlines, "--k");
            xticks(app.UIAxes2, xlines);
            xlabels = strings(1, app.ofsymbolsEditField.Value);
            for idx = 1:length(xlabels)
                xlabels(idx) = string(idx) + "T_s";
            end
            xticklabels(app.UIAxes2, xlabels);
        end

        % Button pushed function: TimeDomainSignalButton
        function TimeDomainSignalButtonPushed(app, event)
            f = uifigure;
            popup_uiaxes = uiaxes(f, Position=[10 10 550 400]);
            hold(popup_uiaxes, "on");
            time = ((0:(double(app.simconsts.simstep_sz * app.sim.curr_step) - 1)) / app.simconsts.Fs);
            plot(popup_uiaxes, time, app.modded_steps(:));
            xlim(popup_uiaxes, [0, app.ofsymbolsEditField.Value * (1 / app.symbolfreqEditField.Value)]);
            title(popup_uiaxes, "Time-Domain Signal Modulated with Frequency Hopping", FontSize=20);
            xlabel(popup_uiaxes, "time(s)", FontSize=18);
            ylabel(popup_uiaxes, "amplitude", FontSize=18);
            xlines = zeros(1, app.ofsymbolsEditField.Value);
            for idx = 1:app.ofsymbolsEditField.Value
                xlines(idx) = idx * (1 / app.symbolfreqEditField.Value);
            end
            xline(popup_uiaxes, xlines, "--k");
            xticks(popup_uiaxes, xlines);
            xlabels = strings(1, app.ofsymbolsEditField.Value);
            for idx = 1:length(xlabels)
                xlabels(idx) = string(idx) + "T_s";
            end
            xticklabels(popup_uiaxes, xlabels);
            hold(popup_uiaxes, "off");
        end

        % Button pushed function: FourierTransformButton
        function FourierTransformButtonPushed(app, event)
            f1 = uifigure();

            if app.BandpassFilterCheckBox.Value
                channel_o_interest = app.ChannelEditField.Value;
                lo = app.CarrierFrequencyEditField.Value + ...
                    app.simconsts.freq_channels(channel_o_interest).f0 - (app.simconsts.interchannel_spacing / 2);
                hi = app.CarrierFrequencyEditField.Value + ...
                    app.simconsts.freq_channels(channel_o_interest).f1 + (app.simconsts.interchannel_spacing / 2);
                signal = bandpass(app.modded_steps(:), [lo, hi], app.simconsts.Fs);
            else
                signal = app.modded_steps(:);
            end
        
            
            nfft = 100000000;
            f_fft = fftshift(fft(signal, nfft));

            f = app.simconsts.Fs * (-nfft/2 : nfft/2 - 1) / nfft;
            scaled_f = (f - 2.4e9) / 1e3;
            scaled_fft = 2 * abs(f_fft) / length(signal);

            popup_uiaxes = uiaxes(f1, Position=[10 10 550 400]);
            plot(popup_uiaxes, scaled_f, scaled_fft);
            carrier_line = xline(popup_uiaxes, 0, "r", "Carrier", DisplayName="Carrier Frequency");
            carrier_line.LabelVerticalAlignment = "middle";
            carrier_line.LabelHorizontalAlignment = "center";
            title(popup_uiaxes, "FFT of a Frequency-Hopping Modulated Carrier Wave (2.4GHz)", FontSize=20);
            legend(popup_uiaxes, "Modulated Signal", "Carrier Frequency");
            ylabel(popup_uiaxes, "Amplitude", FontSize=18);
            xlabel(popup_uiaxes, "Offset from Carrier Frequency (kHz)", FontSize=18);
            lim = (app.simconsts.freq_channels(end, end) + app.InterchannelSpacingEditField.Value) * 1e-3;
            xlim(popup_uiaxes, [-lim, lim])
            xticks(popup_uiaxes, -lim:50:lim);
        end

        % Button pushed function: SpectrogramButton
        function SpectrogramButtonPushed(app, event)
            nfft = 100000000;
            f = figure();
            hold on
            spectrogram(app.modded_steps(:), hamming(1 / app.symbolfreqEditField.Value), ...
                0, nfft, app.simconsts.Fs, "yaxis", "power");
%             set(gca, "XTick", (0:50:500), "XTickLabel", (0:50:500));
%             axs = f.Children;
%             data_objs = axs(2).Children;
%             tmpYData = data_objs.YData;
%             data_objs(1).YData = (tmpYData - 2.4) * 1e6;
%             ylim([0, 175]);
%             ylabel("Offset from Carrier Frequency (kHz)");
%             set(gca, "YTick", (0:25:175), "YTickLabel", (0:25:175));
%             linesx = (0:50:500);
%             lines = gobjects(size(linesx)); 
%             for i = 1:numel(linesx)
%                 lines(i) = xline(linesx(i)); 
%             end
%             caxis([-60, -30]);
%             title("STFT of Frequency-Modulated Signal");
            hold off
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {487, 487};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {207, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CarrierFrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.CarrierFrequencyEditFieldLabel.Text = 'Carrier Frequency';
            app.CarrierFrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CarrierFrequencyEditField.Value = 2400000000;
            app.SimulationParametersLabel = uilabel(app.LeftPanel);
            app.SimulationParametersLabel.Text = 'Simulation Parameters';
            app.FsGranularityEditFieldLabel = uilabel(app.LeftPanel);
            app.FsGranularityEditFieldLabel.Text = 'Fs Granularity';
            app.FsGranularityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FsGranularityEditField.Value = 4;
            app.simstepspersymbolEditFieldLabel = uilabel(app.LeftPanel);
            app.simstepspersymbolEditFieldLabel.Text = 'simsteps per symbol';
            app.simstepspersymbolEditField = uieditfield(app.LeftPanel, 'numeric');
            app.simstepspersymbolEditField.Value = 10;
            app.FbBaseEditFieldLabel = uilabel(app.LeftPanel);
            app.FbBaseEditFieldLabel.Text = 'Fb Base';
            app.FbBaseEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FbBaseEditField.Value = 75000;
            app.ofsymbolsEditFieldLabel = uilabel(app.LeftPanel);
            app.ofsymbolsEditFieldLabel.Text = '# of symbols';
            app.ofsymbolsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ofsymbolsEditField.Value = 10;
            app.amplitudeEditFieldLabel = uilabel(app.LeftPanel);
            app.amplitudeEditFieldLabel.Text = 'amplitude';
            app.amplitudeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.amplitudeEditField.Value = 2.8284;
            app.InterchannelSpacingEditFieldLabel = uilabel(app.LeftPanel);
            app.InterchannelSpacingEditFieldLabel.Text = 'Interchannel Spacing';
            app.InterchannelSpacingEditField = uieditfield(app.LeftPanel, 'numeric');
            app.InterchannelSpacingEditField.Value = 50000;
            app.FbStepEditFieldLabel = uilabel(app.LeftPanel);
            app.FbStepEditFieldLabel.Text = 'Fb Step';
            app.FbStepEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FbStepEditField.Value = 25000;
            app.symbolfreqEditFieldLabel = uilabel(app.LeftPanel);
            app.symbolfreqEditFieldLabel.Text = 'symbol freq';
            app.symbolfreqEditField = uieditfield(app.LeftPanel, 'numeric');
            app.symbolfreqEditField.Value = 100000;
            app.VanAttaelementsEditFieldLabel = uilabel(app.LeftPanel);
            app.VanAttaelementsEditFieldLabel.Text = 'Van Atta elements';
            app.VanAttaelementsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.VanAttaelementsEditField.Value = 4;
            app.BasestationNoisedbEditFieldLabel = uilabel(app.LeftPanel);
            app.BasestationNoisedbEditFieldLabel.Text = 'Basestation Noise (db)';
            app.BasestationNoisedbEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BasestationNoisedbEditField.Value = -100;
            app.ModulationOrderEditFieldLabel = uilabel(app.LeftPanel);
            app.ModulationOrderEditFieldLabel.Text = 'Modulation Order';
            app.ModulationOrderEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ModulationOrderEditField.Value = 2;
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TagLocationsTab = uitab(app.TabGroup);
            app.TagLocationsTab.Title = 'Tag Locations';
            app.UIAxes = uiaxes(app.TagLocationsTab);
            title(app.UIAxes, 'Tag Locations')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.XEditField = uieditfield(app.TagLocationsTab, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditField = uieditfield(app.TagLocationsTab, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditField = uieditfield(app.TagLocationsTab, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.UITable_3 = uitable(app.TagLocationsTab);
            app.UITable_3.ColumnName = {'idx'; 'x'; 'y'; 'z'; 'distance'; 'type'};
            app.UITable_3.RowName = {};
            app.UITable_3.ColumnSortable = true;
            app.TypeDropDown = uidropdown(app.TagLocationsTab);
            app.TypeDropDown.Items = {'FREQ_HOP', 'FSK_LO', 'FSK_HI', 'OOK'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'FSK_LO';
            app.AddButton = uibutton(app.TagLocationsTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.NumTagsEditField = uieditfield(app.TagLocationsTab, 'numeric');
            app.NumTagsEditField.Value = 2;
            app.RandomFChannelCheckBox = uicheckbox(app.TagLocationsTab);
            app.RandomFChannelCheckBox.Text = 'Random FChannel';
            app.RandomFChannelCheckBox.Value = true;
            app.RandomCreationLabel = uilabel(app.TagLocationsTab);
            app.RandomCreationLabel.Text = 'Random Creation';
            app.ManualCreationLabel = uilabel(app.TagLocationsTab);
            app.ManualCreationLabel.Text = 'Manual Creation';
            app.CreateButton = uibutton(app.TagLocationsTab, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.deleteidxEditField = uieditfield(app.TagLocationsTab, 'numeric');
            app.deleteidxEditField.Value = 1;
            app.DeleteButton = uibutton(app.TagLocationsTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.MaxZEditField = uieditfield(app.TagLocationsTab, 'numeric');
            app.MaxZEditField.Value = 3;
            app.MaxYEditField = uieditfield(app.TagLocationsTab, 'numeric');
            app.MaxYEditField.Value = 3;
            app.MaxXEditField = uieditfield(app.TagLocationsTab, 'numeric');
            app.MaxXEditField.Value = 3;
            app.XEditFieldLabel = uilabel(app.TagLocationsTab);
            app.XEditFieldLabel.Text = 'X';
            app.YEditFieldLabel = uilabel(app.TagLocationsTab);
            app.YEditFieldLabel.Text = 'Y';
            app.ZEditFieldLabel = uilabel(app.TagLocationsTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.TypeDropDownLabel = uilabel(app.TagLocationsTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.NumTagsEditFieldLabel = uilabel(app.TagLocationsTab);
            app.NumTagsEditFieldLabel.Text = 'Num Tags';
            app.deleteidxEditFieldLabel = uilabel(app.TagLocationsTab);
            app.deleteidxEditFieldLabel.Text = 'delete idx';
            app.MaxZEditFieldLabel = uilabel(app.TagLocationsTab);
            app.MaxZEditFieldLabel.Text = 'Max Z';
            app.MaxYEditFieldLabel = uilabel(app.TagLocationsTab);
            app.MaxYEditFieldLabel.Text = 'Max Y';
            app.MaxXEditFieldLabel = uilabel(app.TagLocationsTab);
            app.MaxXEditFieldLabel.Text = 'Max X';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.UIAxes2 = uiaxes(app.SimulationTab);
            title(app.UIAxes2, 'Simulator Output')
            xlabel(app.UIAxes2, 'Time (t)')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.SimulateButton = uibutton(app.SimulationTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.SimulationLabel = uilabel(app.SimulationTab);
            app.SimulationLabel.Text = 'Simulation';
            app.DataSigButton = uibutton(app.SimulationTab, 'push');
            app.DataSigButton.ButtonPushedFcn = createCallbackFcn(app, @DataSigButtonPushed, true);
            app.DataSigButton.Text = 'Data Sig';
            app.ResetButton = uibutton(app.SimulationTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.AlignButton = uibutton(app.SimulationTab, 'push');
            app.AlignButton.ButtonPushedFcn = createCallbackFcn(app, @AlignButtonPushed, true);
            app.AlignButton.Text = 'Align';
            app.UITable_4 = uitable(app.SimulationTab);
            app.UITable_4.ColumnName = {'tag_idx'; 'known'; 'received'; 'ber'};
            app.UITable_4.RowName = {};
            app.UITable_4.ColumnSortable = true;
            app.DemodulateButton = uibutton(app.SimulationTab, 'push');
            app.DemodulateButton.ButtonPushedFcn = createCallbackFcn(app, @DemodulateButtonPushed, true);
            app.DemodulateButton.Text = 'Demodulate';
            app.NumSimStepsEditFieldLabel = uilabel(app.SimulationTab);
            app.NumSimStepsEditFieldLabel.Text = 'Num SimSteps';
            app.NumSimStepsEditField = uieditfield(app.SimulationTab, 'numeric');
            app.NumSimStepsEditField.Value = 110;
            app.TagidxEditFieldLabel = uilabel(app.SimulationTab);
            app.TagidxEditFieldLabel.Text = 'Tag idx';
            app.TagidxEditField = uieditfield(app.SimulationTab, 'numeric');
            app.TagidxEditField.Value = 1;
            app.SNRdbEditFieldLabel = uilabel(app.SimulationTab);
            app.SNRdbEditFieldLabel.Text = 'SNR (db)';
            app.SNRdbEditField = uieditfield(app.SimulationTab, 'numeric');
            app.SNRdbEditField.Value = 20;
            app.ApplyNoiseCheckBox = uicheckbox(app.SimulationTab);
            app.ApplyNoiseCheckBox.Text = 'Apply Noise';
            app.ApplyBasestationNoiseCheckBox = uicheckbox(app.SimulationTab);
            app.ApplyBasestationNoiseCheckBox.Text = 'Apply Basestation Noise';
            app.RandomBitstreamCheckBox = uicheckbox(app.SimulationTab);
            app.RandomBitstreamCheckBox.Text = 'Random Bitstream';
            app.RandomBitstreamCheckBox.Value = true;
            app.CustomBitstreamEditFieldLabel = uilabel(app.SimulationTab);
            app.CustomBitstreamEditFieldLabel.Text = 'Custom Bitstream';
            app.CustomBitstreamEditField = uieditfield(app.SimulationTab, 'text');
            app.CustomBitstreamEditField.Value = '01';
            app.FrequencyHoppingTab = uitab(app.TabGroup);
            app.FrequencyHoppingTab.Title = 'Frequency Hopping';
            app.UIAxes3 = uiaxes(app.FrequencyHoppingTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.FrequencyHoppingTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.PatternLengthEditFieldLabel = uilabel(app.FrequencyHoppingTab);
            app.PatternLengthEditFieldLabel.Text = 'Pattern Length';
            app.PatternLengthEditField = uieditfield(app.FrequencyHoppingTab, 'numeric');
            app.PatternLengthEditField.Value = 3;
            app.NumChannelsEditFieldLabel = uilabel(app.FrequencyHoppingTab);
            app.NumChannelsEditFieldLabel.Text = 'Num Channels';
            app.NumChannelsEditField = uieditfield(app.FrequencyHoppingTab, 'numeric');
            app.NumChannelsEditField.Value = 5;
            app.SimulateButton_2 = uibutton(app.FrequencyHoppingTab, 'push');
            app.SimulateButton_2.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton_2.Text = 'Simulate';
            app.TimeDomainSignalButton = uibutton(app.FrequencyHoppingTab, 'push');
            app.TimeDomainSignalButton.ButtonPushedFcn = createCallbackFcn(app, @TimeDomainSignalButtonPushed, true);
            app.TimeDomainSignalButton.Text = 'Time Domain Signal';
            app.FourierTransformButton = uibutton(app.FrequencyHoppingTab, 'push');
            app.FourierTransformButton.ButtonPushedFcn = createCallbackFcn(app, @FourierTransformButtonPushed, true);
            app.FourierTransformButton.Text = 'Fourier Transform';
            app.ResetButton_2 = uibutton(app.FrequencyHoppingTab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.ActiveTagsEditFieldLabel = uilabel(app.FrequencyHoppingTab);
            app.ActiveTagsEditFieldLabel.Text = 'Active Tags';
            app.ActiveTagsEditField = uieditfield(app.FrequencyHoppingTab, 'numeric');
            app.ActiveTagsEditField.Value = 2;
            app.ChannelEditFieldLabel = uilabel(app.FrequencyHoppingTab);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.ChannelEditField = uieditfield(app.FrequencyHoppingTab, 'numeric');
            app.ChannelEditField.Value = 1;
            app.BandpassFilterCheckBox = uicheckbox(app.FrequencyHoppingTab);
            app.BandpassFilterCheckBox.Text = 'Bandpass Filter';
            app.SpectrogramButton = uibutton(app.FrequencyHoppingTab, 'push');
            app.SpectrogramButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrogramButtonPushed, true);
            app.SpectrogramButton.Text = 'Spectrogram';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end